classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HandSwitch            matlab.ui.control.RockerSwitch
        HandSwitchLabel       matlab.ui.control.Label
        arm5Slider            matlab.ui.control.Slider
        arm5SliderLabel       matlab.ui.control.Label
        arm4Slider            matlab.ui.control.Slider
        arm4SliderLabel       matlab.ui.control.Label
        arm3Slider            matlab.ui.control.Slider
        arm3SliderLabel       matlab.ui.control.Label
        arm2Slider            matlab.ui.control.Slider
        arm2Slider_2Label     matlab.ui.control.Label
        arm1Slider            matlab.ui.control.Slider
        arm1Slider_2Label     matlab.ui.control.Label
        baseSlider            matlab.ui.control.Slider
        baseSliderLabel       matlab.ui.control.Label
        MovingSwitch          matlab.ui.control.Switch
        MovingSwitchLabel     matlab.ui.control.Label
        CoordXEditFieldLabel  matlab.ui.control.Label
        CoordXEditField       matlab.ui.control.EditField
        CoordYEditFieldLabel  matlab.ui.control.Label
        CoordYEditField       matlab.ui.control.EditField
        CoordZEditFieldLabel  matlab.ui.control.Label
        CoordZEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        microcontroller;
        s1;
        s2;
        s3;
        s4;
        s5;
        s6;
        s7;
        s8;
        q1;
        q2;
        q3;
        q4;
        q5;
        q6;
        activeMovement = 1;
        
    end
    
    methods (Access = private)
        
        function moveArm(~, serv, oldVal, newVal)
            if(oldVal>newVal)
                for angle = (oldVal):-0.003:(newVal)
                    writePosition(serv, angle);
                    pause(0.0001);
                end
            else
                for angle = (oldVal):0.003:(newVal)
                    writePosition(serv, angle);
                    pause(0.0001);
                end
            end
            
        end


        function results = Trans(~, x,y,z)
            results = [1 0 0 x; 0 1 0 y; 0 0 1 z; 0 0 0 1];
        end
        
        function results = Rot(~, x, y, z)
            eul = [x y z];
            rotmXYZ = eul2rotm(eul, 'XYZ');
            results=eye(4);
            results(1:3,1:3)=rotmXYZ;
        end
        
        
        function results = finalPositon(app)
            ang1 =pi *app.q1/360;
            ang2 =pi *app.q2/180;
            ang3 =pi *app.q3/180;
            ang4 =pi *app.q4/180;
            ang5 =pi *app.q5/180;
            ang6 =pi *app.q6/180;
            
            p1 = Rot(app,0,0,ang1)*Trans(app,0,0, 5.6)*Rot(app,-pi/2,0,0);
            p2 = Trans(app, 10.7,0,0)*Rot(app,0,0,ang2);
            p3 = Trans(app, 9.5,0,0)*Rot(app,pi/2,0,0)*Rot(app,0,0,ang3);
            p4 = Trans(app, 3.5,0,0)*Rot(app,-pi/2,0,0)*Rot(app,0,0,ang4);
            p5 = Rot(app,pi/2,0,0)*Rot(app,0,0,ang5);
            p6 = Trans(app, 1.8,0,0)*Rot(app,0,0,ang6);
            
            results = (p1*p2*p3*p4*p5*p6*Trans(app, 8,0,0))*[1 1 1 1]';
              
        end
        
        
        function DisplayCoordinates(app, coord)
            app.CoordXEditField.Value = string(coord(1));
            app.CoordYEditField.Value = string(coord(2));
            app.CoordZEditField.Value = string(coord(3));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.microcontroller = arduino();
            app.s1 = servo(app.microcontroller, 'D2');
            app.s2 = servo(app.microcontroller, 'D3');
            app.s3 = servo(app.microcontroller, 'D4');
            app.s4 = servo(app.microcontroller, 'D5');
            app.s5 = servo(app.microcontroller, 'D6');
            app.s6 = servo(app.microcontroller, 'D7');
            app.s7 = servo(app.microcontroller, 'D8');
            app.s8 = servo(app.microcontroller, 'D9');
            app.arm5Slider.Value = readPosition(app.s2)*180;
            app.arm4Slider.Value = readPosition(app.s3)*180;
            app.arm3Slider.Value = readPosition(app.s4)*180;
            app.arm2Slider.Value = readPosition(app.s5)*180;
            app.arm1Slider.Value = readPosition(app.s6)*180;
            app.baseSlider.Value = readPosition(app.s7)*180 + readPosition(app.s8)*180;
            app.q6 = readPosition(app.s2)*180;
            app.q5 = readPosition(app.s3)*180;
            app.q4 = readPosition(app.s4)*180;
            app.q3 = readPosition(app.s5)*180;
            app.q2 = readPosition(app.s6)*180;
            app.q1 = readPosition(app.s7)*180 + readPosition(app.s8)*180;
            DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: HandSwitch
        function HandSwitchValueChanged(app, event)
            value = app.HandSwitch.Value;
            if(value == "open")
                writePosition(app.s1, 0.5);
            end
            if(value == "closed")
                writePosition(app.s1, 0);
            end
        end

        % Value changed function: arm5Slider
        function arm5SliderValueChanged(app, event)
            value = app.arm5Slider.Value;
            if(app.activeMovement == 1)
                moveArm(app, app.s2, app.q6/180,value/180);
            end
           app.q6 = value; 
           DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: arm4Slider
        function arm4SliderValueChanged(app, event)
            value = app.arm4Slider.Value;
            if(app.activeMovement == 1)
                moveArm(app, app.s3, app.q5/180,value/180);
            end
            app.q5 = value; 
            DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: arm3Slider
        function arm3SliderValueChanged(app, event)
            value = app.arm3Slider.Value; 
            if(app.activeMovement == 1)
                moveArm(app, app.s4, app.q4/180,value/180);
            end
            app.q4 = value;
            DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: arm2Slider
        function arm2SliderValueChanged(app, event)
            value = app.arm2Slider.Value; 
            if(app.activeMovement == 1)
                moveArm(app, app.s5, app.q3/180,value/180);
            end
            app.q3 = value;
            DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: arm1Slider
        function arm1SliderValueChanged(app, event)
            value = app.arm1Slider.Value;
            if(app.activeMovement == 1)
                moveArm(app, app.s6, app.q2/180,value/180);
            end
            app.q2 = value; 
            DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: baseSlider
        function baseSliderValueChanged(app, event)
            value = app.baseSlider.Value;
            if(app.activeMovement == 1)
                moveArm(app, app.s7, app.q1/360,value/360);
                moveArm(app, app.s8, app.q1/360,value/360);
            end
            app.q1 = value; 
            DisplayCoordinates(app, finalPositon(app));
        end

        % Value changed function: MovingSwitch
        function MovingSwitchValueChanged(app, event)
            value = app.MovingSwitch.Value;
            if(value == "Active")
                app.activeMovement = 1;
                moveArm(app, app.s2, readPosition(app.s2), app.q6/180);
                moveArm(app, app.s3, readPosition(app.s3), app.q5/180);
                moveArm(app, app.s4, readPosition(app.s4), app.q4/180);
                moveArm(app, app.s5, readPosition(app.s5), app.q3/180);
                moveArm(app, app.s6, readPosition(app.s6), app.q2/180);
                moveArm(app, app.s7, readPosition(app.s7), app.q1/360);
                moveArm(app, app.s8, readPosition(app.s8), app.q1/360);
            end
            if(value == "Passive")
                app.activeMovement = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HandSwitch = uiswitch(app.UIFigure, 'rocker');
            app.HandSwitch.Items = {'closed', 'open'};
            app.HandSwitch.ValueChangedFcn = createCallbackFcn(app, @HandSwitchValueChanged, true);
            app.HandSwitch.Value = 'closed';
            app.HandSwitchLabel = uilabel(app.UIFigure);
            app.HandSwitchLabel.Text = 'Hand';
            app.arm5Slider = uislider(app.UIFigure);
            app.arm5Slider.Limits = [0 180];
            app.arm5Slider.ValueChangedFcn = createCallbackFcn(app, @arm5SliderValueChanged, true);
            app.arm5SliderLabel = uilabel(app.UIFigure);
            app.arm5SliderLabel.Text = 'arm 5';
            app.arm4Slider = uislider(app.UIFigure);
            app.arm4Slider.Limits = [0 180];
            app.arm4Slider.ValueChangedFcn = createCallbackFcn(app, @arm4SliderValueChanged, true);
            app.arm4SliderLabel = uilabel(app.UIFigure);
            app.arm4SliderLabel.Text = 'arm 4';
            app.arm3Slider = uislider(app.UIFigure);
            app.arm3Slider.Limits = [0 180];
            app.arm3Slider.ValueChangedFcn = createCallbackFcn(app, @arm3SliderValueChanged, true);
            app.arm3SliderLabel = uilabel(app.UIFigure);
            app.arm3SliderLabel.Text = 'arm 3';
            app.arm2Slider = uislider(app.UIFigure);
            app.arm2Slider.Limits = [0 180];
            app.arm2Slider.ValueChangedFcn = createCallbackFcn(app, @arm2SliderValueChanged, true);
            app.arm2Slider_2Label = uilabel(app.UIFigure);
            app.arm2Slider_2Label.Text = 'arm 2';
            app.arm1Slider = uislider(app.UIFigure);
            app.arm1Slider.Limits = [0 180];
            app.arm1Slider.ValueChangedFcn = createCallbackFcn(app, @arm1SliderValueChanged, true);
            app.arm1Slider_2Label = uilabel(app.UIFigure);
            app.arm1Slider_2Label.Text = 'arm 1';
            app.baseSlider = uislider(app.UIFigure);
            app.baseSlider.Limits = [0 360];
            app.baseSlider.ValueChangedFcn = createCallbackFcn(app, @baseSliderValueChanged, true);
            app.baseSliderLabel = uilabel(app.UIFigure);
            app.baseSliderLabel.Text = 'base';
            app.MovingSwitch = uiswitch(app.UIFigure, 'slider');
            app.MovingSwitch.Items = {'Active', 'Passive'};
            app.MovingSwitch.ValueChangedFcn = createCallbackFcn(app, @MovingSwitchValueChanged, true);
            app.MovingSwitch.Value = 'Active';
            app.MovingSwitchLabel = uilabel(app.UIFigure);
            app.MovingSwitchLabel.Text = 'Moving';
            app.CoordXEditFieldLabel = uilabel(app.UIFigure);
            app.CoordXEditFieldLabel.Text = 'Coord X';
            app.CoordXEditField = uieditfield(app.UIFigure, 'text');
            app.CoordYEditFieldLabel = uilabel(app.UIFigure);
            app.CoordYEditFieldLabel.Text = 'Coord Y';
            app.CoordYEditField = uieditfield(app.UIFigure, 'text');
            app.CoordZEditFieldLabel = uilabel(app.UIFigure);
            app.CoordZEditFieldLabel.Text = 'Coord Z';
            app.CoordZEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end